classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        LaplacesolutionPanel            matlab.ui.container.Panel
        SolveLaplaceProblemButton       matlab.ui.control.Button
        PlotgratingButton               matlab.ui.control.Button
        PlotunitcellButton              matlab.ui.control.Button
        thEditField                     matlab.ui.control.NumericEditField
        thEditFieldLabel                matlab.ui.control.Label
        PlotaxaxacubeButton             matlab.ui.control.Button
        HFSSFlochetPortSimulationPanel  matlab.ui.container.Panel
        nHSweepPanel                    matlab.ui.container.Panel
        StopEditField_3                 matlab.ui.control.NumericEditField
        StopEditField_3Label            matlab.ui.control.Label
        NEditField_3                    matlab.ui.control.NumericEditField
        NEditField_3Label               matlab.ui.control.Label
        StartEditField_3                matlab.ui.control.NumericEditField
        StartEditField_3Label           matlab.ui.control.Label
        ActivateCheckBox_3              matlab.ui.control.CheckBox
        SimulateanisotropicbehaviourCheckBox  matlab.ui.control.CheckBox
        ThresholdSweepPanel             matlab.ui.container.Panel
        StopEditField_2                 matlab.ui.control.NumericEditField
        StopEditField_2Label            matlab.ui.control.Label
        NEditField_2                    matlab.ui.control.NumericEditField
        NEditField_2Label               matlab.ui.control.Label
        StartEditField_2                matlab.ui.control.NumericEditField
        StartEditField_2Label           matlab.ui.control.Label
        ActivateCheckBox_2              matlab.ui.control.CheckBox
        NofPointsEditField              matlab.ui.control.NumericEditField
        ofPointsEditFieldLabel          matlab.ui.control.Label
        SimulationnameEditField         matlab.ui.control.EditField
        SimulationnameEditFieldLabel    matlab.ui.control.Label
        fstopGHzEditField               matlab.ui.control.NumericEditField
        fstopGHzEditFieldLabel          matlab.ui.control.Label
        fstartGHzEditField              matlab.ui.control.NumericEditField
        fstartGHzEditFieldLabel         matlab.ui.control.Label
        CreatescriptButton              matlab.ui.control.Button
        GeneratelatticePanel            matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        NunitsTab                       matlab.ui.container.Tab
        GenerateNUnitsLatticeButton     matlab.ui.control.Button
        N_t2EditField                   matlab.ui.control.NumericEditField
        N_t2EditFieldLabel              matlab.ui.control.Label
        N_t3EditField                   matlab.ui.control.NumericEditField
        N_t3EditFieldLabel              matlab.ui.control.Label
        N_t1EditField                   matlab.ui.control.NumericEditField
        N_t1EditFieldLabel              matlab.ui.control.Label
        BoxTab                          matlab.ui.container.Tab
        zmaxEditField                   matlab.ui.control.NumericEditField
        zmaxEditFieldLabel              matlab.ui.control.Label
        zminEditField                   matlab.ui.control.NumericEditField
        zminEditFieldLabel              matlab.ui.control.Label
        ymaxEditField                   matlab.ui.control.NumericEditField
        ymaxEditFieldLabel              matlab.ui.control.Label
        yminEditField                   matlab.ui.control.NumericEditField
        yminEditFieldLabel              matlab.ui.control.Label
        xmaxEditField                   matlab.ui.control.NumericEditField
        xmaxEditFieldLabel              matlab.ui.control.Label
        xminEditField                   matlab.ui.control.NumericEditField
        xminEditFieldLabel              matlab.ui.control.Label
        GenerateBoxLatticeButton        matlab.ui.control.Button
        CylinderTab                     matlab.ui.container.Tab
        heightEditField                 matlab.ui.control.NumericEditField
        heightEditFieldLabel            matlab.ui.control.Label
        radiusEditField                 matlab.ui.control.NumericEditField
        radiusEditFieldLabel            matlab.ui.control.Label
        GenerateCylinderLatticeButton   matlab.ui.control.Button
        Tab                             matlab.ui.container.Tab
        UnitCelltoSTLButton             matlab.ui.control.Button
        PWEMsettingsPanel               matlab.ui.container.Panel
        LongWavelengthAnisotropicCharacterizationButton  matlab.ui.control.Button
        PWEMThresholdSweepPanel         matlab.ui.container.Panel
        StopEditField                   matlab.ui.control.NumericEditField
        StopEditFieldLabel              matlab.ui.control.Label
        NEditField                      matlab.ui.control.NumericEditField
        NEditFieldLabel                 matlab.ui.control.Label
        StartEditField                  matlab.ui.control.NumericEditField
        StartEditFieldLabel             matlab.ui.control.Label
        ActivateCheckBox                matlab.ui.control.CheckBox
        NsolEditField                   matlab.ui.control.NumericEditField
        NsolEditFieldLabel              matlab.ui.control.Label
        NbetaEditField                  matlab.ui.control.NumericEditField
        NbetaLabel                      matlab.ui.control.Label
        pwemN3EditField                 matlab.ui.control.NumericEditField
        N3EditField_2Label              matlab.ui.control.Label
        pwemN2EditField                 matlab.ui.control.NumericEditField
        N2EditField_2Label              matlab.ui.control.Label
        pwemN1EditField                 matlab.ui.control.NumericEditField
        N1EditField_2Label              matlab.ui.control.Label
        NREditField                     matlab.ui.control.NumericEditField
        NREditFieldLabel                matlab.ui.control.Label
        NQEditField                     matlab.ui.control.NumericEditField
        NQEditFieldLabel                matlab.ui.control.Label
        NPEditField                     matlab.ui.control.NumericEditField
        NPEditFieldLabel                matlab.ui.control.Label
        ComputeBandDiagramButton        matlab.ui.control.Button
        CrystalTextArea                 matlab.ui.control.TextArea
        CrystalTextAreaLabel            matlab.ui.control.Label
        ThresholdSweepButton            matlab.ui.control.Button
        CrystalstructurePanel           matlab.ui.container.Panel
        nLEditField                     matlab.ui.control.NumericEditField
        nLEditFieldLabel                matlab.ui.control.Label
        nHEditField                     matlab.ui.control.NumericEditField
        nHEditFieldLabel                matlab.ui.control.Label
        ButtonGroup                     matlab.ui.container.ButtonGroup
        HexagonalButton                 matlab.ui.control.RadioButton
        BodyCenterCubicButton           matlab.ui.control.RadioButton
        FaceCenterCubicButton           matlab.ui.control.RadioButton
        SimpleCubicButton               matlab.ui.control.RadioButton
        N3EditField                     matlab.ui.control.NumericEditField
        N3EditFieldLabel                matlab.ui.control.Label
        N2EditField                     matlab.ui.control.NumericEditField
        N2EditFieldLabel                matlab.ui.control.Label
        N1EditField                     matlab.ui.control.NumericEditField
        N1EditFieldLabel                matlab.ui.control.Label
        aEditField                      matlab.ui.control.NumericEditField
        aLabel                          matlab.ui.control.Label
        SpatialharmonicscontrolPanel    matlab.ui.container.Panel
        SaveButton                      matlab.ui.control.Button
        LoadButton                      matlab.ui.control.Button
        SpatialHarmonicsListBox         matlab.ui.control.ListBox
        SpatialHarmonicsListBoxLabel    matlab.ui.control.Label
        NewButton                       matlab.ui.control.Button
        DeleteButton                    matlab.ui.control.Button
        ApplyButton                     matlab.ui.control.Button
        phiEditField                    matlab.ui.control.NumericEditField
        phiEditFieldLabel               matlab.ui.control.Label
        AEditField                      matlab.ui.control.NumericEditField
        AEditFieldLabel                 matlab.ui.control.Label
        t2EditField                     matlab.ui.control.NumericEditField
        t2EditFieldLabel                matlab.ui.control.Label
        t3EditField                     matlab.ui.control.NumericEditField
        t3EditFieldLabel                matlab.ui.control.Label
        t1EditField                     matlab.ui.control.NumericEditField
        t1EditFieldLabel                matlab.ui.control.Label
        ThresholdSlider                 matlab.ui.control.Slider
        ThresholdSliderLabel            matlab.ui.control.Label
        UIAxesCrystalStructure          matlab.ui.control.UIAxes
        UIAxesUnitCell                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        a=1;                        %default unit cell size
        spatialHarmonics=struct('nH',0);  % struct to save spatial harmonics
        N1=21; N2=21; N3=21;
        thres=0.5;
        GA;
        UC;
        crystal;
        %fill default spatial harmonics
    end
    
    methods (Access = private)
        function addHarmonic(app,name,t1,t2,t3,A,phi)
            if ~isfield(app.spatialHarmonics,name)
                app.spatialHarmonics.([char(name)])=struct('t1',t1,'t2',t2,'t3',t3,'A',A,'phi',phi);
                app.SpatialHarmonicsListBox.Items{end+1}=char(name);
                app.spatialHarmonics.nH=app.spatialHarmonics.nH+1;
            else
                %display error
            end
        end
        
        function updateHarmonic(app,name,t1,t2,t3,A,phi)
            if isfield(app.spatialHarmonics,[name])
                app.spatialHarmonics.([name])=struct('t1',t1,'t2',t2,'t3',t3,'A',A,'phi',phi);
            else
                %display error
            end
        end
        
        function removeHarmonic(app,name)
            if isfield(app.spatialHarmonics,[name])
                app.spatialHarmonics=rmfield(app.spatialHarmonics,[name]);
                app.spatialHarmonics.nH=app.spatialHarmonics.nH-1;
                %remove from list
                I=find(strcmp(app.SpatialHarmonicsListBox.Items,name));
                app.SpatialHarmonicsListBox.Items(I)=[];
%                 displayHarmonic(app,app.SpatialHarmonicsListBox.Value);
            else
                %display error
            end
        end
        
        function removeAllHarmonics(app)
            sHnames=fieldnames(app.spatialHarmonics);
            for ii=1:length(sHnames)
                if isstruct(app.spatialHarmonics.(sHnames{ii}))
                    removeHarmonic(app,sHnames{ii});
                end
            end
        end
        
        function displayHarmonic(app,name)
            h=app.spatialHarmonics.([name]);
            app.t1EditField.Value=h.t1;
            app.t2EditField.Value=h.t2;
            app.t3EditField.Value=h.t3;
            app.AEditField.Value=h.A;
            app.phiEditField.Value=h.phi;
        end
        
        function computeAnalogGrating(app)
            GA=zeros(app.N1,app.N2,app.N3);
            
            %unit cell coordinate system
            XU=app.crystal.unitcell.XU;
            YU=app.crystal.unitcell.YU;
            ZU=app.crystal.unitcell.ZU;
            
            sHnames=fieldnames(app.spatialHarmonics);
            for ii=1:length(sHnames)
                if isstruct(app.spatialHarmonics.(sHnames{ii}))
                    h=app.spatialHarmonics.(sHnames{ii});
                    Kx=h.t1;
                    Ky=h.t2;
                    Kz=h.t3;
                    GAtemp=exp(1j*(Kx*XU+Ky*YU+Kz*ZU+h.phi*ones(size(ZU))));
                    GA=GA+h.A*GAtemp;
                end
            end
            
            %clean up numerical noise
            GA=real(GA);
            GA=GA-min(GA(:));
            GA=GA/max(GA(:));
%             app.GA=ifftshift(GA);
            app.GA=GA;
            
            %plot unit cell
            ax=app.UIAxesUnitCell;
            app.UC=struct();
            [app.crystal.unitcell.UCB,app.crystal.unitcell.surface,app.crystal.unitcell.caps]=plotUnitCell(ax,app.crystal.unitcell,app.GA, app.thres);
        end
        
        function setCrystal(app,structure)
            % primitve lattice vectors of selected crystal structure   
            switch structure
                case 'sc'
                    t1=app.a*[1; 0; 0];
                    t2=app.a*[0; 1; 0];
                    t3=app.a*[0; 0; 1];
                case 'fcc'
                    t1=app.a/2*[0; 1; 1];
                    t2=app.a/2*[1; 0; 1];
                    t3=app.a/2*[1; 1; 0];
                case 'bcc'
                    t1=app.a/2*[1; 1; -1];
                    t2=app.a/2*[1; -1; 1];
                    t3=app.a/2*[-1; 1; 1];
                case 'hex'
                    t1=app.a/2*[1; -sqrt(3); 0];
                    t2=app.a/2*[1; sqrt(3); 0];
                    t3=app.a*[0; 0; 1];
                otherwise
            end
            %compute reciprocal lattice vectors
            T1=2*pi*(cross(t2,t3))/dot(t1,cross(t2,t3));
            T2=2*pi*(cross(t3,t1))/dot(t2,cross(t3,t1));
            T3=2*pi*(cross(t1,t2))/dot(t3,cross(t1,t2));
            %add default harmonics
            removeAllHarmonics(app);
            addHarmonic(app,'K_1',T1(1),T1(2),T1(3),1,0);
            addHarmonic(app,'K_2',T2(1),T2(2),T2(3),1,0);
            addHarmonic(app,'K_3',T3(1),T3(2),T3(3),1,0);
            displayHarmonic(app,'K_1');
            %calculate key points of symmetry
            switch structure
                case 'sc'
                    %calcluate the key points of symmetry
                    PG = [1;1;1]*1e-3;
                    PR=1/2*T1+1/2*T2+1/2*T3;
                    PX=1/2*T2;
                    PM=1/2*T1+1/2*T2;
                    
                    %define path around IBZ
%                     KP=[PR PG PX PM];
%                     KL={'R','\Gamma','X','M'};
                    KP=[PG PX PM PG PR PX];
                    KL={'\Gamma','X','M','\Gamma','R','X'};
                case 'fcc'
                    %calculate key points of symmetry
                    PG = [1;1;1]*1e-3;
                    PX=0.5*T2+0.5*T3;
                    PL=0.5*T1+0.5*T2+0.5*T3;
                    PK=3/8*T1+3/8*T2+3/4*T3;
                    PU=1/4*T1+5/8*T2+5/8*T3;
                    PW=1/4*T1+1/2*T2+3/4*T3;
                    
                    %define path around IBZ
%                     KP=[PU PX PG PL PK PW PU];
%                     KL={'U','X','\Gamma','L','K','W','U'};
                    KP=[PG PX PW PK PG PL PU PW PL PK];
                    KL={'\Gamma','X','W','K','\Gamma','L','U','W','L','K'};
                case 'bcc'
                    %calcluate the key points of symmetry
                    PG = [1;1;1]*1e-3;
                    PN=0.5*T2;
                    PH=-0.5*T1+0.5*T2+0.5*T3;
                    PP=1/4*T1+1/4*T2+1/4*T3;
                    
                    %define path around IBZ
                    KP=[PN PG PH PP];
                    KL={'N','\Gamma','H','P'};
                case 'hex'
                    %calcluate the key points of symmetry
                    PG = [1;1;1]*1e-3;
                    PA=0.5*T3;
                    PH=1/3*T1+1/3*T2+0.5*T3;
                    PK=1/3*T1+1/3*T2;
                    PL=0.5*T1+0.5*T3;
                    PM=0.5*T1;
                    %define path around IBZ
                    KP=[PG PM PK PG PA PL PH PA PL PM PK PH];
                    KL={'\Gamma','M','K','\Gamma','A','L','H','A','L','M','K','H'};
                otherwise
            end
            
            %construct an oblique meshgrid for the unit cell
            p=linspace(-0.5,0.5,app.N1);
            q=linspace(-0.5,0.5,app.N2);
            r=linspace(-0.5,0.5,app.N3);
            [Q,P,R]=meshgrid(q,p,r);
            XU=P*t1(1)+Q*t2(1)+R*t3(1);
            YU=P*t1(2)+Q*t2(2)+R*t3(2);
            ZU=P*t1(3)+Q*t2(3)+R*t3(3);
            unitcell=struct('XU',XU,'YU',YU,'ZU',ZU,'N1',app.N1,'N2',app.N2,'N3',app.N3,'a',app.a);
            
            %asssign to crystal struct
            app.crystal=struct('t1',t1,'t2',t2,'t3',t3,'T1',T1,'T2',T2,'T3',T3,'KP',KP,'KL',strjoin(KL),'unitcell',unitcell);
            
            %plot crystal structure
            ax=app.UIAxesCrystalStructure;
            plotCrystalStructure(ax,app.crystal)
            
            %compute coordinate system in unit cell
            computeAnalogGrating(app);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %enter default values to controls
            app.aEditField.Value=app.a;
            app.N1EditField.Value=app.N1;
            app.N2EditField.Value=app.N2;
            app.N3EditField.Value=app.N3;
            app.ThresholdSlider.Value=app.thres;
            app.thEditField.Value=app.thres
            %select default crystal structure sc
            setCrystal(app,'sc');           
%             computeAnalogGrating(app);            
        end

        % Value changed function: SpatialHarmonicsListBox
        function SpatialHarmonicsListBoxValueChanged(app, event)
            value = app.SpatialHarmonicsListBox.Value;
            displayHarmonic(app,value);
        end

        % Value changed function: ThresholdSlider
        function ThresholdSliderValueChanged(app, event)
            value = app.ThresholdSlider.Value;
            app.thres=value;
            app.thEditField.Value=value;
            computeAnalogGrating(app);
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            value = app.SpatialHarmonicsListBox.Value;
            removeHarmonic(app,value);
            computeAnalogGrating(app);
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            t1=app.t1EditField.Value;
            t2=app.t2EditField.Value;
            t3=app.t3EditField.Value;
            A=app.AEditField.Value;
            phi=app.phiEditField.Value;
            name=app.SpatialHarmonicsListBox.Value;
            updateHarmonic(app,name,t1,t2,t3,A,phi);
            computeAnalogGrating(app);
        end

        % Button pushed function: NewButton
        function NewButtonPushed(app, event)
            x=app.t1EditField.Value;
            y=app.t2EditField.Value;
            z=app.t3EditField.Value;
            A=app.AEditField.Value;
            phi=app.phiEditField.Value;
            %promt for a name
            name=inputdlg('Identifier of the new harmonic:');
            %check if name is already given
            if ~ismember(app.SpatialHarmonicsListBox.Items,name)
                %add harmonic
                addHarmonic(app,name,x,y,z,A,phi);
            else
                uialert(app.UIFigure,'Identifier already in use!', 'Error');
            end
        end

        % Button pushed function: ThresholdSweepButton
        function ThresholdSweepButtonPushed(app, event)
            thresholdSweep(25,app.crystal,app.GA);
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'Simple Cubic'
                    setCrystal(app,'sc')
                case 'Face Center Cubic'                    
                    setCrystal(app,'fcc')
                case 'Body Center Cubic'
                    setCrystal(app,'bcc')
                case 'Hexagonal'
                    setCrystal(app,'hex')
                otherwise
            end            
        end

        % Button pushed function: ComputeBandDiagramButton
        function ComputeBandDiagramButtonPushed(app, event)
            pwemParam=struct();
            pwemParam.N1=app.pwemN1EditField.Value;
            pwemParam.N2=app.pwemN2EditField.Value;
            pwemParam.N3=app.pwemN3EditField.Value;
            pwemParam.nH=app.nHEditField.Value;
            pwemParam.nL=app.nLEditField.Value;
            pwemParam.NP=app.NPEditField.Value;
            pwemParam.NQ=app.NQEditField.Value;
            pwemParam.NR=app.NREditField.Value;
            pwemParam.Nbeta=app.NbetaEditField.Value;
            pwemParam.Nsol=app.NsolEditField.Value;
            if app.ActivateCheckBox.Value
                th_start=app.StartEditField.Value;
                th_stop=app.StopEditField.Value;
                th_n=app.NEditField.Value;
                if th_stop<th_start
                    
                else
                    ths=linspace(th_start,th_stop,th_n);
                    epEffMG1=zeros(length(ths),1);
                    epEffMG2=zeros(length(ths),1);
                    epEffLW=zeros(length(ths),1);
                    ff=zeros(length(ths),1);
                    %WN=zeros(length(ths),pwemParam.Nbeta);
                    for ii=1:length(ths)
                        [BETA,WNtemp,epEffMG1(ii),epEffMG2(ii),epEffLW(ii),ff(ii)]=pwem3d_nonmagnetic_v3(app.crystal, pwemParam, app.spatialHarmonics,ths(ii),0);
                        if ii==1
                            WN=zeros(length(ths),size(BETA,2));
                        end
                        WN(ii,:)=WNtemp(1,:);
                    end
                    figure
                    subplot(1,2,1);
                    plot(1:size(WN,2),WN)
                    subplot(1,2,2); hold all;
                    plot(ths,epEffMG1)
%                     plot(ths,epEffMG1)
                    plot(ths,epEffLW)
                    epEffLW
                end
            else
                [BETA,WNtemp,epEffMG1,epEffMG2,epEffLW,ff]=pwem3d_nonmagnetic_v3(app.crystal, pwemParam, app.spatialHarmonics,app.thres,1);
                epEffMG1
                epEffMG2
                epEffLW
            end
            
        end

        % Button pushed function: UnitCelltoSTLButton
        function UnitCelltoSTLButtonPushed(app, event)
            exportUnitCellToSTLmanually(app.crystal.unitcell);
        end

        % Button pushed function: GenerateNUnitsLatticeButton
        function GenerateNUnitsLatticeButtonPushed(app, event)
            N1=app.N_t1EditField.Value-1;
            N2=app.N_t2EditField.Value-1;
            N3=app.N_t3EditField.Value-1;
            generateLattice(app.crystal,N1,N2,N3);
        end

        % Button pushed function: GenerateBoxLatticeButton
        function GenerateBoxLatticeButtonPushed(app, event)
            xmin=app.xminEditField.Value;
            xmax=app.xmaxEditField.Value;
            ymin=app.yminEditField.Value;
            ymax=app.ymaxEditField.Value;
            zmin=app.zminEditField.Value;
            zmax=app.zmaxEditField.Value;
            if ~(xmax-xmin) > 0
                uialert(app.UIFigure,'Invalid x boundaries!', 'Error');
                return;
            end
            if ~(ymax-ymin) > 0
                uialert(app.UIFiugre,'Invalid y boundaries!', 'Error');
            end
            if ~(zmax-zmin) > 0
                uialert(app.UIFigure,'Invalid z boundaries!', 'Erro');
            end
            generateBoxLattice(app.crystal,app.spatialHarmonics, xmin,xmax,ymin,ymax,zmin,zmax,app.thres);
            
        end

        % Value changed function: aEditField
        function aEditFieldValueChanged(app, event)
            value = app.aEditField.Value;
            app.a = value;
            selectedButton = app.ButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'Simple Cubic'
                    setCrystal(app,'sc')
                case 'Face Center Cubic'                    
                    setCrystal(app,'fcc')
                case 'Body Center Cubic'
                    setCrystal(app,'bcc')
                otherwise
            end            
        end

        % Button pushed function: GenerateCylinderLatticeButton
        function GenerateCylinderLatticeButtonPushed(app, event)
            radius=app.radiusEditField.Value;
            height=app.heightEditField.Value;
            if radius <= 0
                uialert(app.UIFigure,'Invalid radius!', 'Error');
                return;
            end
            if height <= 0
                uialert(app.UIFigure,'Invalid height!', 'Error');
                return;
            end
            generateCylinderLattice_O(app.crystal,app.spatialHarmonics,radius,height,app.thres);
            
        end

        % Button pushed function: CreatescriptButton
        function CreatescriptButtonPushed(app, event)
            f_start=app.fstartGHzEditField.Value;
            f_stop=app.fstopGHzEditField.Value;
            if f_start<=f_stop 
                simName=app.SimulationnameEditField.Value;
                nPoints=app.NofPointsEditField.Value;
                runnHSweep=app.ActivateCheckBox_3.Value;
                if runnHSweep
                    nH_start=app.StartEditField_3.Value;
                    nH_stop=app.StopEditField_3.Value;
                    nH_n=app.NEditField_3.Value;
                    nH=linspace(nH_start,nH_stop,nH_n);
                else
                    nH=app.nHEditField.Value;
                end
                nL=app.nLEditField.Value;
                runAnisotropic=app.SimulateanisotropicbehaviourCheckBox.Value;
                runThresholdSweep=app.ActivateCheckBox_2.Value;
                dirName=uigetdir('.\','Select folder');
                if ~isnumeric(dirName)
                    if runThresholdSweep
                        th_start=app.StartEditField_2.Value;
                        th_stop=app.StopEditField_2.Value;
                        th_n=app.NEditField_2.Value;
                        ths=linspace(th_start,th_stop,th_n);
                        for ii=1:length(ths)
                            %change app unitcell threshold and recompute
                            app.ThresholdSlider.Value=ths(ii);
                            app.thres=ths(ii);
                            computeAnalogGrating(app);
                            %export unitcell
                            selectedButton = app.ButtonGroup.SelectedObject;
                            switch selectedButton.Text
                                case 'Simple Cubic'
                                    exportUnitCellToSTL_v1(app.crystal.unitcell,dirName,[simName '_th' strrep(num2str(ths(ii)),'.','p')],1);
                                case 'Face Center Cubic'  
                                    uc=getAXAXAcube(app.crystal,app.spatialHarmonics,app.thres);
                                    exportUnitCellToSTL_v1(uc,dirName,[simName '_th' strrep(num2str(ths(ii)),'.','p')],1);
                                case 'Body Center Cubic'
                                    uc=getAXAXAcube(app.crystal,app.spatialHarmonics,app.thres);
                                    exportUnitCellToSTL_v1(uc,dirName,[simName '_th' strrep(num2str(ths(ii)),'.','p')],0);
                                case 'Hexagonal'
%                                     exportUnitCellToSTL(uc,dirName,[simName '_th' strrep(num2str(ths(ii)),'.','p')]);
                                otherwise
                            end                              
                        end
                    else
                        ths=NaN;
                        %export unitcell
                        selectedButton = app.ButtonGroup.SelectedObject;
                        switch selectedButton.Text
                            case 'Simple Cubic'
                                exportUnitCellToSTL_v1(app.crystal.unitcell,dirName,simName,0);
                            case 'Face Center Cubic'  
                                uc=getAXAXAcube(app.crystal,app.spatialHarmonics,app.thres);
                                exportUnitCellToSTL_v1(uc,dirName,simName,1);
                            case 'Body Center Cubic'
                                uc=getAXAXAcube(app.crystal,app.spatialHarmonics,app.thres);
                                exportUnitCellToSTL_v1(uc,dirName,simName,0);
                            case 'Hexagonal'
%                                     exportUnitCellToSTL(uc,dirName,[simName '_th' strrep(num2str(ths(ii)),'.','p')]);
                            otherwise
                        end                           
                    end                    
                    a=app.crystal.unitcell.a;
                    crystalStructure=selectedButton.Text;
                    save([dirName '\simParam.mat'],"f_start","f_stop","a","crystalStructure","simName","nPoints","nH","nL","runAnisotropic","runThresholdSweep","ths","runnHSweep")
                end
            else
                uialert(app.UIFigure,'Invalid start frequency!', 'Error');
            end            
        end

        % Button pushed function: PlotaxaxacubeButton
        function PlotaxaxacubeButtonPushed(app, event)
            plotAXAXAcube(app.crystal,app.spatialHarmonics,app.thres)
        end

        % Value changed function: N1EditField
        function N1EditFieldValueChanged(app, event)
            value = app.N1EditField.Value;
            app.N1 = value;
            selectedButton = app.ButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'Simple Cubic'
                    setCrystal(app,'sc')
                case 'Face Center Cubic'                    
                    setCrystal(app,'fcc')
                case 'Body Center Cubic'
                    setCrystal(app,'bcc')
                otherwise
            end            
        end

        % Value changed function: N2EditField
        function N2EditFieldValueChanged(app, event)
            value = app.N2EditField.Value;
            app.N2 = value;
            selectedButton = app.ButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'Simple Cubic'
                    setCrystal(app,'sc')
                case 'Face Center Cubic'                    
                    setCrystal(app,'fcc')
                case 'Body Center Cubic'
                    setCrystal(app,'bcc')
                otherwise
            end           
        end

        % Value changed function: N3EditField
        function N3EditFieldValueChanged(app, event)
            value = app.N3EditField.Value;
            app.N3 = value;
            selectedButton = app.ButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'Simple Cubic'
                    setCrystal(app,'sc')
                case 'Face Center Cubic'                    
                    setCrystal(app,'fcc')
                case 'Body Center Cubic'
                    setCrystal(app,'bcc')
                otherwise
            end           
        end

        % Value changed function: thEditField
        function thEditFieldValueChanged(app, event)
            value = app.thEditField.Value;
            app.ThresholdSlider.Value=value;
            app.thres=value;
            computeAnalogGrating(app);
        end

        % Callback function
        function PlotunitcellButtonPushed(app, event)
            
%             plotUnitCell_ext(app.crystal,app.spatialHarmonics,app.thres)
        end

        % Button pushed function: PlotunitcellButton
        function PlotunitcellButtonPushed2(app, event)
            plotUnitCell_ext(app.crystal.unitcell,app.GA, app.thres);
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [file,path]=uigetfile('*.mat');
            if ~isequal(file,0)
                lsh=load(fullfile(path,file));
                if isfield(lsh,'sh')
                    removeAllHarmonics(app);
                    harmonicsToAdd=fieldnames(lsh.sh);
                    for ii=1:length(harmonicsToAdd)
                        sh=lsh.sh.(harmonicsToAdd{ii})
                        addHarmonic(app,harmonicsToAdd{ii},sh.t1,sh.t2,sh.t3,sh.A,sh.phi);
                    end
                    computeAnalogGrating(app);
                else
                    
                end
            end
        end

        % Button pushed function: PlotgratingButton
        function PlotgratingButtonPushed(app, event)
            plotGrating(app.crystal.unitcell,app.GA, app.thres);
        end

        % Button pushed function: SolveLaplaceProblemButton
        function SolveLaplaceProblemButtonPushed(app, event)
            solveLaplaceProblem(app.crystal.unitcell,app.GA,app.thres,app.nHEditField.Value,app.nLEditField.Value);
        end

        % Button pushed function: 
        % LongWavelengthAnisotropicCharacterizationButton
        function LongWavelengthAnisotropicCharacterizationButtonPushed(app, event)
            pwemParam=struct();
            pwemParam.N1=app.pwemN1EditField.Value;
            pwemParam.N2=app.pwemN2EditField.Value;
            pwemParam.N3=app.pwemN3EditField.Value;
            pwemParam.nH=app.nHEditField.Value;
            pwemParam.nL=app.nLEditField.Value;
            pwemParam.NP=app.NPEditField.Value;
            pwemParam.NQ=app.NQEditField.Value;
            pwemParam.NR=app.NREditField.Value;
            pwemParam.Nbeta=app.NbetaEditField.Value;
            pwemParam.Nsol=app.NsolEditField.Value;
            
            longWaveLengthAnisotropicCharacterization(app.crystal, pwemParam, app.spatialHarmonics,app.thres);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxesUnitCell = uiaxes(app.UIFigure);
            title(app.UIAxesUnitCell, 'Title')
            xlabel(app.UIAxesUnitCell, 'X')
            ylabel(app.UIAxesUnitCell, 'Y')
            app.UIAxesCrystalStructure = uiaxes(app.UIFigure);
            title(app.UIAxesCrystalStructure, 'Title')
            xlabel(app.UIAxesCrystalStructure, 'X')
            ylabel(app.UIAxesCrystalStructure, 'Y')
            app.ThresholdSliderLabel = uilabel(app.UIFigure);
            app.ThresholdSliderLabel.Text = 'Threshold';
            app.ThresholdSlider = uislider(app.UIFigure);
            app.ThresholdSlider.Limits = [0 1];
            app.ThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @ThresholdSliderValueChanged, true);
            app.ThresholdSlider.Value = 0.5;
            app.SpatialharmonicscontrolPanel = uipanel(app.UIFigure);
            app.SpatialharmonicscontrolPanel.Title = 'Spatial harmonics control';
            app.t1EditFieldLabel = uilabel(app.SpatialharmonicscontrolPanel);
            app.t1EditFieldLabel.Text = 't1';
            app.t1EditField = uieditfield(app.SpatialharmonicscontrolPanel, 'numeric');
            app.t3EditFieldLabel = uilabel(app.SpatialharmonicscontrolPanel);
            app.t3EditFieldLabel.Text = 't3';
            app.t3EditField = uieditfield(app.SpatialharmonicscontrolPanel, 'numeric');
            app.t2EditFieldLabel = uilabel(app.SpatialharmonicscontrolPanel);
            app.t2EditFieldLabel.Text = 't2';
            app.t2EditField = uieditfield(app.SpatialharmonicscontrolPanel, 'numeric');
            app.AEditFieldLabel = uilabel(app.SpatialharmonicscontrolPanel);
            app.AEditFieldLabel.Text = 'A';
            app.AEditField = uieditfield(app.SpatialharmonicscontrolPanel, 'numeric');
            app.phiEditFieldLabel = uilabel(app.SpatialharmonicscontrolPanel);
            app.phiEditFieldLabel.Text = 'phi';
            app.phiEditField = uieditfield(app.SpatialharmonicscontrolPanel, 'numeric');
            app.ApplyButton = uibutton(app.SpatialharmonicscontrolPanel, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.DeleteButton = uibutton(app.SpatialharmonicscontrolPanel, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.NewButton = uibutton(app.SpatialharmonicscontrolPanel, 'push');
            app.NewButton.ButtonPushedFcn = createCallbackFcn(app, @NewButtonPushed, true);
            app.NewButton.Text = 'New';
            app.SpatialHarmonicsListBoxLabel = uilabel(app.SpatialharmonicscontrolPanel);
            app.SpatialHarmonicsListBoxLabel.Text = 'Spatial Harmonics ';
            app.SpatialHarmonicsListBox = uilistbox(app.SpatialharmonicscontrolPanel);
            app.SpatialHarmonicsListBox.Items = {};
            app.SpatialHarmonicsListBox.ValueChangedFcn = createCallbackFcn(app, @SpatialHarmonicsListBoxValueChanged, true);
            app.SpatialHarmonicsListBox.Value = {};
            app.LoadButton = uibutton(app.SpatialharmonicscontrolPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.SaveButton = uibutton(app.SpatialharmonicscontrolPanel, 'push');
            app.SaveButton.Text = 'Save';
            app.CrystalstructurePanel = uipanel(app.UIFigure);
            app.CrystalstructurePanel.Title = 'Crystal structure';
            app.aLabel = uilabel(app.CrystalstructurePanel);
            app.aLabel.Text = 'a';
            app.aEditField = uieditfield(app.CrystalstructurePanel, 'numeric');
            app.aEditField.Limits = [0 Inf];
            app.aEditField.ValueChangedFcn = createCallbackFcn(app, @aEditFieldValueChanged, true);
            app.aEditField.Value = 1;
            app.N1EditFieldLabel = uilabel(app.CrystalstructurePanel);
            app.N1EditFieldLabel.Text = 'N1';
            app.N1EditField = uieditfield(app.CrystalstructurePanel, 'numeric');
            app.N1EditField.Limits = [0 Inf];
            app.N1EditField.ValueChangedFcn = createCallbackFcn(app, @N1EditFieldValueChanged, true);
            app.N2EditFieldLabel = uilabel(app.CrystalstructurePanel);
            app.N2EditFieldLabel.Text = 'N2';
            app.N2EditField = uieditfield(app.CrystalstructurePanel, 'numeric');
            app.N2EditField.Limits = [0 Inf];
            app.N2EditField.ValueChangedFcn = createCallbackFcn(app, @N2EditFieldValueChanged, true);
            app.N3EditFieldLabel = uilabel(app.CrystalstructurePanel);
            app.N3EditFieldLabel.Text = 'N3';
            app.N3EditField = uieditfield(app.CrystalstructurePanel, 'numeric');
            app.N3EditField.Limits = [0 Inf];
            app.N3EditField.ValueChangedFcn = createCallbackFcn(app, @N3EditFieldValueChanged, true);
            app.ButtonGroup = uibuttongroup(app.CrystalstructurePanel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = 'Button Group';
            app.SimpleCubicButton = uiradiobutton(app.ButtonGroup);
            app.SimpleCubicButton.Text = 'Simple Cubic';
            app.SimpleCubicButton.Value = true;
            app.FaceCenterCubicButton = uiradiobutton(app.ButtonGroup);
            app.FaceCenterCubicButton.Text = 'Face Center Cubic';
            app.BodyCenterCubicButton = uiradiobutton(app.ButtonGroup);
            app.BodyCenterCubicButton.Text = 'Body Center Cubic';
            app.HexagonalButton = uiradiobutton(app.ButtonGroup);
            app.HexagonalButton.Text = 'Hexagonal';
            app.nHEditFieldLabel = uilabel(app.CrystalstructurePanel);
            app.nHEditFieldLabel.Text = 'nH';
            app.nHEditField = uieditfield(app.CrystalstructurePanel, 'numeric');
            app.nHEditField.Limits = [0 Inf];
            app.nHEditField.Value = 3;
            app.nLEditFieldLabel = uilabel(app.CrystalstructurePanel);
            app.nLEditFieldLabel.Text = 'nL';
            app.nLEditField = uieditfield(app.CrystalstructurePanel, 'numeric');
            app.nLEditField.Limits = [0 Inf];
            app.nLEditField.Value = 1;
            app.ThresholdSweepButton = uibutton(app.UIFigure, 'push');
            app.ThresholdSweepButton.ButtonPushedFcn = createCallbackFcn(app, @ThresholdSweepButtonPushed, true);
            app.ThresholdSweepButton.Text = 'Threshold Sweep';
            app.CrystalTextAreaLabel = uilabel(app.UIFigure);
            app.CrystalTextAreaLabel.Text = 'Crystal';
            app.CrystalTextArea = uitextarea(app.UIFigure);
            app.PWEMsettingsPanel = uipanel(app.UIFigure);
            app.PWEMsettingsPanel.Title = 'PWEM settings';
            app.ComputeBandDiagramButton = uibutton(app.PWEMsettingsPanel, 'push');
            app.ComputeBandDiagramButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeBandDiagramButtonPushed, true);
            app.ComputeBandDiagramButton.Text = {'Compute '; 'Band'; 'Diagram'};
            app.NPEditFieldLabel = uilabel(app.PWEMsettingsPanel);
            app.NPEditFieldLabel.Text = 'NP';
            app.NPEditField = uieditfield(app.PWEMsettingsPanel, 'numeric');
            app.NPEditField.Limits = [0 Inf];
            app.NPEditField.Value = 5;
            app.NQEditFieldLabel = uilabel(app.PWEMsettingsPanel);
            app.NQEditFieldLabel.Text = 'NQ';
            app.NQEditField = uieditfield(app.PWEMsettingsPanel, 'numeric');
            app.NQEditField.Limits = [0 Inf];
            app.NQEditField.Value = 5;
            app.NREditFieldLabel = uilabel(app.PWEMsettingsPanel);
            app.NREditFieldLabel.Text = 'NR';
            app.NREditField = uieditfield(app.PWEMsettingsPanel, 'numeric');
            app.NREditField.Limits = [0 Inf];
            app.NREditField.Value = 5;
            app.N1EditField_2Label = uilabel(app.PWEMsettingsPanel);
            app.N1EditField_2Label.Text = 'N1';
            app.pwemN1EditField = uieditfield(app.PWEMsettingsPanel, 'numeric');
            app.pwemN1EditField.Limits = [0 Inf];
            app.pwemN1EditField.Value = 128;
            app.N2EditField_2Label = uilabel(app.PWEMsettingsPanel);
            app.N2EditField_2Label.Text = 'N2';
            app.pwemN2EditField = uieditfield(app.PWEMsettingsPanel, 'numeric');
            app.pwemN2EditField.Limits = [0 Inf];
            app.pwemN2EditField.Value = 128;
            app.N3EditField_2Label = uilabel(app.PWEMsettingsPanel);
            app.N3EditField_2Label.Text = 'N3';
            app.pwemN3EditField = uieditfield(app.PWEMsettingsPanel, 'numeric');
            app.pwemN3EditField.Limits = [0 Inf];
            app.pwemN3EditField.Value = 128;
            app.NbetaLabel = uilabel(app.PWEMsettingsPanel);
            app.NbetaLabel.Text = 'Nbeta';
            app.NbetaEditField = uieditfield(app.PWEMsettingsPanel, 'numeric');
            app.NbetaEditField.Limits = [0 Inf];
            app.NbetaEditField.Value = 50;
            app.NsolEditFieldLabel = uilabel(app.PWEMsettingsPanel);
            app.NsolEditFieldLabel.Text = 'Nsol';
            app.NsolEditField = uieditfield(app.PWEMsettingsPanel, 'numeric');
            app.NsolEditField.Limits = [0 Inf];
            app.NsolEditField.Value = 5;
            app.PWEMThresholdSweepPanel = uipanel(app.PWEMsettingsPanel);
            app.PWEMThresholdSweepPanel.Title = 'PWEM Threshold Sweep';
            app.ActivateCheckBox = uicheckbox(app.PWEMThresholdSweepPanel);
            app.ActivateCheckBox.Text = 'Activate';
            app.StartEditFieldLabel = uilabel(app.PWEMThresholdSweepPanel);
            app.StartEditFieldLabel.Text = 'Start';
            app.StartEditField = uieditfield(app.PWEMThresholdSweepPanel, 'numeric');
            app.StartEditField.Limits = [0 1];
            app.NEditFieldLabel = uilabel(app.PWEMThresholdSweepPanel);
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.PWEMThresholdSweepPanel, 'numeric');
            app.NEditField.Limits = [1 Inf];
            app.NEditField.RoundFractionalValues = 'on';
            app.NEditField.ValueDisplayFormat = '%.0f';
            app.NEditField.Value = 1;
            app.StopEditFieldLabel = uilabel(app.PWEMThresholdSweepPanel);
            app.StopEditFieldLabel.Text = 'Stop';
            app.StopEditField = uieditfield(app.PWEMThresholdSweepPanel, 'numeric');
            app.StopEditField.Limits = [0 1];
            app.StopEditField.Value = 1;
            app.LongWavelengthAnisotropicCharacterizationButton = uibutton(app.PWEMsettingsPanel, 'push');
            app.LongWavelengthAnisotropicCharacterizationButton.ButtonPushedFcn = createCallbackFcn(app, @LongWavelengthAnisotropicCharacterizationButtonPushed, true);
            app.LongWavelengthAnisotropicCharacterizationButton.Text = 'Long Wavelength Anisotropic Characterization';
            app.UnitCelltoSTLButton = uibutton(app.UIFigure, 'push');
            app.UnitCelltoSTLButton.ButtonPushedFcn = createCallbackFcn(app, @UnitCelltoSTLButtonPushed, true);
            app.UnitCelltoSTLButton.Text = 'Unit Cell to STL';
            app.GeneratelatticePanel = uipanel(app.UIFigure);
            app.GeneratelatticePanel.Title = 'Generate lattice';
            app.TabGroup = uitabgroup(app.GeneratelatticePanel);
            app.NunitsTab = uitab(app.TabGroup);
            app.NunitsTab.Title = 'N units';
            app.N_t1EditFieldLabel = uilabel(app.NunitsTab);
            app.N_t1EditFieldLabel.Text = 'N_t1';
            app.N_t1EditField = uieditfield(app.NunitsTab, 'numeric');
            app.N_t1EditField.Limits = [0 Inf];
            app.N_t1EditField.Value = 5;
            app.N_t3EditFieldLabel = uilabel(app.NunitsTab);
            app.N_t3EditFieldLabel.Text = 'N_t3';
            app.N_t3EditField = uieditfield(app.NunitsTab, 'numeric');
            app.N_t3EditField.Limits = [0 Inf];
            app.N_t3EditField.Value = 1;
            app.N_t2EditFieldLabel = uilabel(app.NunitsTab);
            app.N_t2EditFieldLabel.Text = {'N_t2'; ''};
            app.N_t2EditField = uieditfield(app.NunitsTab, 'numeric');
            app.N_t2EditField.Limits = [0 Inf];
            app.N_t2EditField.Value = 5;
            app.GenerateNUnitsLatticeButton = uibutton(app.NunitsTab, 'push');
            app.GenerateNUnitsLatticeButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateNUnitsLatticeButtonPushed, true);
            app.GenerateNUnitsLatticeButton.Text = 'Generate Lattice';
            app.BoxTab = uitab(app.TabGroup);
            app.BoxTab.Title = 'Box';
            app.GenerateBoxLatticeButton = uibutton(app.BoxTab, 'push');
            app.GenerateBoxLatticeButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateBoxLatticeButtonPushed, true);
            app.GenerateBoxLatticeButton.Text = 'Generate Lattice';
            app.xminEditFieldLabel = uilabel(app.BoxTab);
            app.xminEditFieldLabel.Text = 'x min';
            app.xminEditField = uieditfield(app.BoxTab, 'numeric');
            app.xmaxEditFieldLabel = uilabel(app.BoxTab);
            app.xmaxEditFieldLabel.Text = 'x max';
            app.xmaxEditField = uieditfield(app.BoxTab, 'numeric');
            app.yminEditFieldLabel = uilabel(app.BoxTab);
            app.yminEditFieldLabel.Text = 'y min';
            app.yminEditField = uieditfield(app.BoxTab, 'numeric');
            app.ymaxEditFieldLabel = uilabel(app.BoxTab);
            app.ymaxEditFieldLabel.Text = 'y max';
            app.ymaxEditField = uieditfield(app.BoxTab, 'numeric');
            app.zminEditFieldLabel = uilabel(app.BoxTab);
            app.zminEditFieldLabel.Text = 'z min';
            app.zminEditField = uieditfield(app.BoxTab, 'numeric');
            app.zmaxEditFieldLabel = uilabel(app.BoxTab);
            app.zmaxEditFieldLabel.Text = 'z max';
            app.zmaxEditField = uieditfield(app.BoxTab, 'numeric');
            app.CylinderTab = uitab(app.TabGroup);
            app.CylinderTab.Title = 'Cylinder';
            app.GenerateCylinderLatticeButton = uibutton(app.CylinderTab, 'push');
            app.GenerateCylinderLatticeButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateCylinderLatticeButtonPushed, true);
            app.GenerateCylinderLatticeButton.Text = 'Generate Lattice';
            app.radiusEditFieldLabel = uilabel(app.CylinderTab);
            app.radiusEditFieldLabel.Text = 'radius';
            app.radiusEditField = uieditfield(app.CylinderTab, 'numeric');
            app.heightEditFieldLabel = uilabel(app.CylinderTab);
            app.heightEditFieldLabel.Text = 'height';
            app.heightEditField = uieditfield(app.CylinderTab, 'numeric');
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.HFSSFlochetPortSimulationPanel = uipanel(app.UIFigure);
            app.HFSSFlochetPortSimulationPanel.Title = 'HFSS Flochet Port Simulation';
            app.CreatescriptButton = uibutton(app.HFSSFlochetPortSimulationPanel, 'push');
            app.CreatescriptButton.ButtonPushedFcn = createCallbackFcn(app, @CreatescriptButtonPushed, true);
            app.CreatescriptButton.Text = {'Create '; 'script'};
            app.fstartGHzEditFieldLabel = uilabel(app.HFSSFlochetPortSimulationPanel);
            app.fstartGHzEditFieldLabel.Text = 'f start (GHz)';
            app.fstartGHzEditField = uieditfield(app.HFSSFlochetPortSimulationPanel, 'numeric');
            app.fstartGHzEditField.Limits = [0 Inf];
            app.fstartGHzEditField.Value = 0.1;
            app.fstopGHzEditFieldLabel = uilabel(app.HFSSFlochetPortSimulationPanel);
            app.fstopGHzEditFieldLabel.Text = 'f stop (GHz)';
            app.fstopGHzEditField = uieditfield(app.HFSSFlochetPortSimulationPanel, 'numeric');
            app.fstopGHzEditField.Limits = [0 Inf];
            app.fstopGHzEditField.Value = 10;
            app.SimulationnameEditFieldLabel = uilabel(app.HFSSFlochetPortSimulationPanel);
            app.SimulationnameEditFieldLabel.Text = 'Simulation name';
            app.SimulationnameEditField = uieditfield(app.HFSSFlochetPortSimulationPanel, 'text');
            app.SimulationnameEditField.Value = 'unitcell_hfss_flochetSimulation';
            app.ofPointsEditFieldLabel = uilabel(app.HFSSFlochetPortSimulationPanel);
            app.ofPointsEditFieldLabel.Text = '# of Points';
            app.NofPointsEditField = uieditfield(app.HFSSFlochetPortSimulationPanel, 'numeric');
            app.NofPointsEditField.Limits = [0 Inf];
            app.NofPointsEditField.ValueDisplayFormat = '%.0f';
            app.NofPointsEditField.Value = 401;
            app.ThresholdSweepPanel = uipanel(app.HFSSFlochetPortSimulationPanel);
            app.ThresholdSweepPanel.Title = 'Threshold Sweep';
            app.ActivateCheckBox_2 = uicheckbox(app.ThresholdSweepPanel);
            app.ActivateCheckBox_2.Text = 'Activate';
            app.StartEditField_2Label = uilabel(app.ThresholdSweepPanel);
            app.StartEditField_2Label.Text = 'Start';
            app.StartEditField_2 = uieditfield(app.ThresholdSweepPanel, 'numeric');
            app.StartEditField_2.Limits = [0 1];
            app.NEditField_2Label = uilabel(app.ThresholdSweepPanel);
            app.NEditField_2Label.Text = 'N';
            app.NEditField_2 = uieditfield(app.ThresholdSweepPanel, 'numeric');
            app.NEditField_2.Limits = [1 Inf];
            app.NEditField_2.RoundFractionalValues = 'on';
            app.NEditField_2.ValueDisplayFormat = '%.0f';
            app.NEditField_2.Value = 1;
            app.StopEditField_2Label = uilabel(app.ThresholdSweepPanel);
            app.StopEditField_2Label.Text = 'Stop';
            app.StopEditField_2 = uieditfield(app.ThresholdSweepPanel, 'numeric');
            app.StopEditField_2.Limits = [0 1];
            app.StopEditField_2.Value = 1;
            app.SimulateanisotropicbehaviourCheckBox = uicheckbox(app.HFSSFlochetPortSimulationPanel);
            app.SimulateanisotropicbehaviourCheckBox.Text = {'Simulate anisotropic behaviour'; ''};
            app.nHSweepPanel = uipanel(app.HFSSFlochetPortSimulationPanel);
            app.nHSweepPanel.Title = 'nH Sweep';
            app.ActivateCheckBox_3 = uicheckbox(app.nHSweepPanel);
            app.ActivateCheckBox_3.Text = 'Activate';
            app.StartEditField_3Label = uilabel(app.nHSweepPanel);
            app.StartEditField_3Label.Text = 'Start';
            app.StartEditField_3 = uieditfield(app.nHSweepPanel, 'numeric');
            app.StartEditField_3.Limits = [0 100];
            app.StartEditField_3.Value = 1;
            app.NEditField_3Label = uilabel(app.nHSweepPanel);
            app.NEditField_3Label.Text = 'N';
            app.NEditField_3 = uieditfield(app.nHSweepPanel, 'numeric');
            app.NEditField_3.Limits = [1 Inf];
            app.NEditField_3.RoundFractionalValues = 'on';
            app.NEditField_3.ValueDisplayFormat = '%.0f';
            app.NEditField_3.Value = 1;
            app.StopEditField_3Label = uilabel(app.nHSweepPanel);
            app.StopEditField_3Label.Text = 'Stop';
            app.StopEditField_3 = uieditfield(app.nHSweepPanel, 'numeric');
            app.StopEditField_3.Limits = [1 100];
            app.StopEditField_3.Value = 3;
            app.PlotaxaxacubeButton = uibutton(app.UIFigure, 'push');
            app.PlotaxaxacubeButton.ButtonPushedFcn = createCallbackFcn(app, @PlotaxaxacubeButtonPushed, true);
            app.PlotaxaxacubeButton.Text = 'Plot axaxa cube';
            app.thEditFieldLabel = uilabel(app.UIFigure);
            app.thEditFieldLabel.Text = 'th';
            app.thEditField = uieditfield(app.UIFigure, 'numeric');
            app.thEditField.ValueChangedFcn = createCallbackFcn(app, @thEditFieldValueChanged, true);
            app.PlotunitcellButton = uibutton(app.UIFigure, 'push');
            app.PlotunitcellButton.ButtonPushedFcn = createCallbackFcn(app, @PlotunitcellButtonPushed2, true);
            app.PlotunitcellButton.Text = 'Plot unit cell';
            app.PlotgratingButton = uibutton(app.UIFigure, 'push');
            app.PlotgratingButton.ButtonPushedFcn = createCallbackFcn(app, @PlotgratingButtonPushed, true);
            app.PlotgratingButton.Text = 'Plot grating';
            app.LaplacesolutionPanel = uipanel(app.UIFigure);
            app.LaplacesolutionPanel.Title = 'Laplace solution';
            app.SolveLaplaceProblemButton = uibutton(app.LaplacesolutionPanel, 'push');
            app.SolveLaplaceProblemButton.ButtonPushedFcn = createCallbackFcn(app, @SolveLaplaceProblemButtonPushed, true);
            app.SolveLaplaceProblemButton.Text = 'Solve Laplace Problem';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end